classdef regenEngineTradesViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunningLamp                    matlab.ui.control.Lamp
        RunningLampLabel               matlab.ui.control.Label
        EditFielddt                    matlab.ui.control.NumericEditField
        Volumemm3Label_12              matlab.ui.control.Label
        EditFieldL_chamber_linear      matlab.ui.control.NumericEditField
        Volumemm3Label_11              matlab.ui.control.Label
        EditFieldRc                    matlab.ui.control.NumericEditField
        Volumemm3Label_10              matlab.ui.control.Label
        EditFieldL_chamber_circular_narrow  matlab.ui.control.NumericEditField
        Volumemm3Label_9               matlab.ui.control.Label
        EditFieldalpha                 matlab.ui.control.NumericEditField
        Volumemm3Label_8               matlab.ui.control.Label
        EditFieldR1p                   matlab.ui.control.NumericEditField
        Volumemm3Label_7               matlab.ui.control.Label
        EditFieldR1                    matlab.ui.control.NumericEditField
        Volumemm3Label_6               matlab.ui.control.Label
        EditFieldxN                    matlab.ui.control.NumericEditField
        Volumemm3Label_5               matlab.ui.control.Label
        EditFieldthetaN                matlab.ui.control.NumericEditField
        Volumemm3Label_4               matlab.ui.control.Label
        EditFieldRe                    matlab.ui.control.NumericEditField
        Volumemm3Label_3               matlab.ui.control.Label
        EditFieldL_nozzle_parabolic    matlab.ui.control.NumericEditField
        Volumemm3Label_2               matlab.ui.control.Label
        EditFieldvol_engine            matlab.ui.control.NumericEditField
        Volumemm3Label                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        viewofthroatTab                matlab.ui.container.Tab
        UIAxesview_of_throat           matlab.ui.control.UIAxes
        viewofwholeengineTab           matlab.ui.container.Tab
        UIAxesview_of_whole_engine     matlab.ui.control.UIAxes
        ShowthermalstresslimitsButton  matlab.ui.control.StateButton
        Sliderk_wall                   matlab.ui.control.Slider
        k_wallWmKLabel                 matlab.ui.control.Label
        num_channelsSliderLabel        matlab.ui.control.Label
        Sliderwallt                    matlab.ui.control.Slider
        wallthicknessmmSliderLabel     matlab.ui.control.Label
        Slidermdot                     matlab.ui.control.Slider
        dtmmLabel                      matlab.ui.control.Label
        SliderT_coolant                matlab.ui.control.Slider
        T_coolantdegCSlider_2Label     matlab.ui.control.Label
        Sliderexpansion_ratio          matlab.ui.control.Slider
        expansionratioSliderLabel      matlab.ui.control.Label
        Slidernum_channels             matlab.ui.control.Slider
        SliderOF                       matlab.ui.control.Slider
        OFSliderLabel                  matlab.ui.control.Label
        Sliderd_channel                matlab.ui.control.Slider
        d_channelmmSliderLabel         matlab.ui.control.Label
        Sliderpc                       matlab.ui.control.Slider
        pcpsiSliderLabel               matlab.ui.control.Label
        yaxisDropDown                  matlab.ui.control.DropDown
        yaxisDropDownLabel             matlab.ui.control.Label
        UIAxesk_wall                   matlab.ui.control.UIAxes
        UIAxeswallt                    matlab.ui.control.UIAxes
        UIAxesmdot                     matlab.ui.control.UIAxes
        UIAxesT_coolant                matlab.ui.control.UIAxes
        UIAxesexpansion_ratio          matlab.ui.control.UIAxes
        UIAxesnum_channels             matlab.ui.control.UIAxes
        UIAxesOF                       matlab.ui.control.UIAxes
        UIAxesd_channel                matlab.ui.control.UIAxes
        UIAxespc                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function fRefreshPlots(app, yaxisname)
            % Green
            app.RunningLamp.Color = [0, 1, 0];
            drawnow;
            % For debugging
            disp("Refreshing plots");
            
            % Assume regenEngineTradesCalculator.m is in same folder
            load("regenEngineTradesData.mat");

            % To avoid floating point errors
            tol_low = 1E-12;

            % for showing extra thermal stress data, like limits
            boolextrathermals = app.ShowthermalstresslimitsButton.Value;

            %% Plot data on graphs
            % Go through every graph
            for i_var = 1:length(list_var_names)
                var_name = list_var_names(i_var);
                y_vals = eval(yaxisname);

                y_vals_indices = ones(1, length(list_var_names));
                y_vals_indices = num2cell(y_vals_indices);
                % Finds indices of data arrays we want to navigate to,
                % based on what the sliders are currently set to
                for i_i = 1:length(y_vals_indices)
                    y_vals_indices{i_i} = find(abs(ranges{i_i} - (app.("Slider" + list_var_names(i_i)).Value)) <= tol_low, 1);
                end

                % For each graph, make sure the variable corresponding to
                % that graph is varied, not a constant. So include the
                % entire range
                y_vals_indices{i_var} = 1:length(ranges{i_var});

                % Grab the slice of the data those indices point to
                y_vals = squeeze(y_vals(y_vals_indices{:}));
                % Fixes weird MATLAB quirk where when squeezing everything
                %   but the second dimension of an ndarray, the resultant
                %   is a row vector. But, I want col vectors for the
                %   following switch case
                if i_var == 2
                    y_vals = y_vals(:);
                end

                % Show extra data for some data, like limits or comparable
                % values
                switch yaxisname
                    case "thermstress"
                        if boolextrathermals
                            y_vals(:, 2) = yieldstress_max;
                            y_vals(:, 3) = yieldstress_alloy;
                        end
                    case "Twg"
                        y_vals(:, 2) = T_w_max;
                        y_vals(:, 3) = T_AlSi10Mg_melt;
                    case "P_coolant_min"
                        % if not "varying pc" graph
                        % if i_var ~= 1
                        y_vals(:, 2) = app.("Slider" + list_var_names(i_i)).Value;
                        % end
                end
                try
                    plot(eval("app.UIAxes" + var_name), ranges{i_var}, y_vals);
                    legend(eval("app.UIAxes" + var_name), "off");
                catch
                    error("Couldn't plot, likely problem with y_vals");
                end
            end

            % Legend handling for plots with more than one y range
            switch yaxisname
                case "thermstress"
                    if boolextrathermals
                        for i_var = 1:length(list_var_names)
                            var_name = list_var_names(i_var);
                            legend(eval("app.UIAxes" + var_name), "thermal stress", "max allowable stress", "alloy yield stress");
                        end
                    % else

                    end
                case "Twg"
                    for i_var = 1:length(list_var_names)
                        var_name = list_var_names(i_var);
                        legend(eval("app.UIAxes" + var_name), "hot-side hot wall temp", "max allowable temp", "alloy melt temp");
                    end
                case "P_coolant_min"
                    for i_var = 1:length(list_var_names)
                        % if not "varying pc" graph, show how min. required
                        % coolant press compares to specified chamber press
                        % if i_var ~= 1
                        var_name = list_var_names(i_var);
                        legend(eval("app.UIAxes" + var_name), "min allowable coolant press", "pc");
                        % end
                    end
            end

            %% Display contour data
            data_indices = ones(1, length(list_var_names));
            data_indices = num2cell(data_indices);
            for i_i = 1:length(data_indices)
                data_indices{i_i} = find(abs(ranges{i_i} - (app.("Slider" + list_var_names(i_i)).Value)) <= tol_low, 1);
            end

            % Convert to mm scale
            % vol_engine already in mm^3
            vol_engine = squeeze(vol_engine(data_indices{:}));
            % m to mm
            L_nozzle_parabolic = squeeze(L_nozzle_parabolic(data_indices{:})) .* 10.^3;
            % m to mm
            Re = squeeze(Re(data_indices{:})) .* 10.^3;
            % rad to deg
            thetaN = squeeze(thetaN(data_indices{:})) .* 180 ./ pi;
            % m to mm
            xN = squeeze(xN(data_indices{:})) .* 10.^3;
            % m to mm
            R1 = squeeze(R1(data_indices{:})) .* 10.^3;
            % m to mm
            R1p = squeeze(R1p(data_indices{:})) .* 10.^3;
            % rad to deg
            alpha = squeeze(alpha(data_indices{:})) .* 180 ./ pi;
            % m to mm
            L_chamber_circular_narrow = squeeze(L_chamber_circular_narrow(data_indices{:})) .* 10.^3;
            % m to mm
            Rc  = squeeze(Rc(data_indices{:})) .* 10.^3;
            % m to mm
            L_chamber_linear = squeeze(L_chamber_linear(data_indices{:})) .* 10.^3;
            % in to mm
            dt = squeeze(dt(data_indices{:})) .* 25.4;

            for icv = 1:length(contourvalnames)
                app.("EditField" + contourvalnames(icv)).Value = eval(contourvalnames(icv));
            end

            %% Now show throat view
            num_channels = app.Slidernum_channels.Value;
            wallt = app.Sliderwallt.Value;
            % Draws:
            %  - A circle of diameter D_throat
            %  - N_channels "house-shaped" coolant channels arranged symmetrically
            %  - Each channel’s flat bottom edge is standoff distance away 
            %    from the circle outer radius
            
            cla(app.UIAxesview_of_throat);
            hold(app.UIAxesview_of_throat, "on");
            axis(app.UIAxesview_of_throat, "equal");
        
            % ===== CIRCLE =====
            R = dt/2;
            theta = linspace(0, 2*pi, 500);
            plot(app.UIAxesview_of_throat, R*cos(theta), R*sin(theta), 'b', 'LineWidth', 2);
        
            % ===== CHANNEL SHAPE DEFINITION (local coordinates) =====
            % Let the base of the shape be horizontal, centered at origin
            % Dimensions are arbitrary—change if you want
            side = app.Sliderd_channel.Value;  % square side length
        
            % Square corners:
            % bottom-left  (-s/2, 0)
            % bottom-right ( s/2, 0)
            % top-right    ( s/2, s)
            % top-left     (-s/2, s)
            %
            % On top, attach a 45-45-90 triangle, remove hypotenuse side.
            % Triangle apex above the midpoint (0,s) by height s.
            tri_h = side / 2;  
        
            % Pentagon vertices (in clockwise order)
            baseLeft   = [-side/2, 0];
            baseRight  = [ side/2, 0];
            topRight   = [ side/2, side];
            apex       = [ 0, side + tri_h ];
            topLeft    = [-side/2, side];
        
            houseShape = [ baseLeft;
                           baseRight;
                           topRight;
                           apex;
                           topLeft;
                           baseLeft ]; % close polygon

            % ----- ROTATE SHAPE 90° TO THE RIGHT -----
            rot90cw = [0 1; -1 0];
            houseShape = (rot90cw * houseShape.').';
        
            % ===== PLACE CHANNELS AROUND CIRCLE =====
            for k = 1:num_channels
                % Angle for this channel
                ang = (k-1) * 2*pi/num_channels;
        
                % Distance from origin to base of the shape
                r_place = R + wallt;
        
                % Translation to position the **base midpoint** at correct location
                base_mid = [0, 0]; % in local coords, base is centered at origin
                target = r_place * [cos(ang), sin(ang)];
        
                % Rotation matrix so the shape points radially outward
                Rmat = [cos(ang), -sin(ang);
                        sin(ang),  cos(ang)];
        
                % Apply rotation and translation
                transformed = (Rmat * (houseShape - base_mid)').' + target;
        
                % Plot channel
                plot(app.UIAxesview_of_throat, transformed(:,1), transformed(:,2), 'b', 'LineWidth', 1.5);
            end

            % Outer wall
            outerWallRadius = R + wallt + 2*side;   % channel height = 2*side
            plot(app.UIAxesview_of_throat, outerWallRadius*cos(theta), outerWallRadius*sin(theta), 'b', 'LineWidth', 2);

            %% Now show perspetive view of engine
            ax = app.UIAxesview_of_whole_engine;
            thetaarray = linspace(0, 2 .* pi, size(z_engine, ndims(z_engine)));

            % Graph
            ztograph = squeeze(z_engine(data_indices{:}, :));
            [thetatograph, ztograph] = meshgrid(thetaarray, ztograph);
            rtograph = repmat(squeeze(r_engine(data_indices{:}, :)), 1, length(thetaarray));
            xtograph = rtograph .* cos(thetatograph);
            ytograph = rtograph .* sin(thetatograph);
            surf(ax, xtograph, ytograph, ztograph, 'FaceColor', [0.7 0.7 0.7], 'EdgeColor', 'none');

            % Corner perspective and nice graph stuff
            view(ax, 45, 30);
            camlight(ax);
            lighting(ax, 'gouraud');
            axis(ax, "equal");

            %% Normally have to refresh figure after all that
            % Red
            app.RunningLamp.Color = [1, 0, 0];
            drawnow;
        end
        
        function snapSlider(app, slidername)
            val = app.(slidername).Value;
            mjrs = app.(slidername).MajorTicks;
            idx2 = find(mjrs > val, 1);
            if isempty(idx2)
                idx2 = length(mjrs);
            end
            idx1 = idx2 - 1;
            dif2 = abs(mjrs(idx2) - val);
            dif1 = abs(mjrs(idx1) - val);
            if dif2 <= dif1
                val = mjrs(idx2);
            else
                val = mjrs(idx1);
            end

            app.(slidername).Value = val;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            % Assume file is in same folder
            load("regenEngineTradesData.mat");
            app.yaxisDropDown.Items = ["thermstress (MPa)", "Twg (deg C)", "T_coolant_f (deg C)", "P_coolant_min (psi)", "Isp (s)", "thrust (lbf)", "prop_cost ($)", "dt (in)", "de (in)", "cstar (ft/s)", "eta_cstar (%)", "TWR"];

            for i_var = 1:length(list_var_names)
                try
                    varname = list_var_names(i_var);
                    axesname = "UIAxes" + varname;
                    axesobj = app.(axesname);
                    slidername = "Slider" + varname;
                    sliderobj = app.(slidername);
                    sliderobj.Limits = [ranges{i_var}(1), ranges{i_var}(end)];
                    sliderobj.MajorTicks = ranges{i_var};
                catch
                    if exist(varname, "var")
                        error("Couldn't initiate app for %s data\nrange: %f", varname, ranges{i_var});
                    else
                        error("Couldn't initiate varname %d in app startup", i_var);
                    end
                end
            end

            % Initial plots
            yaxisname = split(string(app.yaxisDropDown.Items(1)), " ");
            yaxisname = yaxisname(1);
            fRefreshPlots(app, yaxisname)
        end

        % Value changed function: yaxisDropDown
        function yaxisDropDownValueChanged(app, event)
            yaxisname = split(string(app.yaxisDropDown.Value), " ");
            yaxisname = yaxisname(1);
            
            % Refresh Plots
            fRefreshPlots(app, yaxisname);
        end

        % Value changed function: Sliderpc
        function SliderpcValueChanged(app, event)
            snapSlider(app, "Sliderpc");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: SliderOF
        function SliderOFValueChanged(app, event)
            snapSlider(app, "SliderOF");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderexpansion_ratio
        function Sliderexpansion_ratioValueChanged(app, event)
            snapSlider(app, "Sliderexpansion_ratio");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Slidermdot
        function SlidermdotValueChanged(app, event)
            snapSlider(app, "Slidermdot");
            yaxisDropDownValueChanged(app, event);
        end

        % Callback function
        function Slidereta_cstarValueChanged(app, event)
            snapSlider(app, "Slidereta_cstar");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderd_channel
        function Sliderd_channelValueChanged(app, event)
            snapSlider(app, "Sliderd_channel");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Slidernum_channels
        function Slidernum_channelsValueChanged(app, event)
            snapSlider(app, "Slidernum_channels");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: SliderT_coolant
        function SliderT_coolantValueChanged(app, event)
            snapSlider(app, "SliderT_coolant");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderwallt
        function SliderwalltValueChanged(app, event)
            snapSlider(app, "Sliderwallt");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: Sliderk_wall
        function Sliderk_wallValueChanged(app, event)
            snapSlider(app, "Sliderk_wall");
            yaxisDropDownValueChanged(app, event);
        end

        % Value changed function: ShowthermalstresslimitsButton
        function ShowthermalstresslimitsButtonValueChanged(app, event)
            value = app.ShowthermalstresslimitsButton.Value;
            yaxisDropDownValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxespc = uiaxes(app.UIFigure);
            title(app.UIAxespc, 'varying pc')
            xlabel(app.UIAxespc, 'X')
            ylabel(app.UIAxespc, 'Y')
            zlabel(app.UIAxespc, 'Z')
            app.UIAxesd_channel = uiaxes(app.UIFigure);
            title(app.UIAxesd_channel, 'varying d channels')
            xlabel(app.UIAxesd_channel, 'X')
            ylabel(app.UIAxesd_channel, 'Y')
            zlabel(app.UIAxesd_channel, 'Z')
            app.UIAxesOF = uiaxes(app.UIFigure);
            title(app.UIAxesOF, 'varying OF')
            xlabel(app.UIAxesOF, 'X')
            ylabel(app.UIAxesOF, 'Y')
            zlabel(app.UIAxesOF, 'Z')
            app.UIAxesnum_channels = uiaxes(app.UIFigure);
            title(app.UIAxesnum_channels, 'varying # of channels')
            xlabel(app.UIAxesnum_channels, 'X')
            ylabel(app.UIAxesnum_channels, 'Y')
            zlabel(app.UIAxesnum_channels, 'Z')
            app.UIAxesexpansion_ratio = uiaxes(app.UIFigure);
            title(app.UIAxesexpansion_ratio, 'varying expansion ratio')
            xlabel(app.UIAxesexpansion_ratio, 'X')
            ylabel(app.UIAxesexpansion_ratio, 'Y')
            zlabel(app.UIAxesexpansion_ratio, 'Z')
            app.UIAxesT_coolant = uiaxes(app.UIFigure);
            title(app.UIAxesT_coolant, 'varying T coolant')
            xlabel(app.UIAxesT_coolant, 'X')
            ylabel(app.UIAxesT_coolant, 'Y')
            zlabel(app.UIAxesT_coolant, 'Z')
            app.UIAxesmdot = uiaxes(app.UIFigure);
            title(app.UIAxesmdot, 'varying mdot')
            xlabel(app.UIAxesmdot, 'X')
            ylabel(app.UIAxesmdot, 'Y')
            zlabel(app.UIAxesmdot, 'Z')
            app.UIAxeswallt = uiaxes(app.UIFigure);
            title(app.UIAxeswallt, 'varying wall thickness')
            xlabel(app.UIAxeswallt, 'X')
            ylabel(app.UIAxeswallt, 'Y')
            zlabel(app.UIAxeswallt, 'Z')
            app.UIAxesk_wall = uiaxes(app.UIFigure);
            title(app.UIAxesk_wall, 'varying k wall')
            xlabel(app.UIAxesk_wall, 'X')
            ylabel(app.UIAxesk_wall, 'Y')
            zlabel(app.UIAxesk_wall, 'Z')
            app.yaxisDropDownLabel = uilabel(app.UIFigure);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.yaxisDropDown = uidropdown(app.UIFigure);
            app.yaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @yaxisDropDownValueChanged, true);
            app.pcpsiSliderLabel = uilabel(app.UIFigure);
            app.pcpsiSliderLabel.Text = {'pc'; '(psi)'};
            app.Sliderpc = uislider(app.UIFigure);
            app.Sliderpc.ValueChangedFcn = createCallbackFcn(app, @SliderpcValueChanged, true);
            app.d_channelmmSliderLabel = uilabel(app.UIFigure);
            app.d_channelmmSliderLabel.Text = {'d_channel'; '(mm)'};
            app.Sliderd_channel = uislider(app.UIFigure);
            app.Sliderd_channel.ValueChangedFcn = createCallbackFcn(app, @Sliderd_channelValueChanged, true);
            app.OFSliderLabel = uilabel(app.UIFigure);
            app.OFSliderLabel.Text = 'OF';
            app.SliderOF = uislider(app.UIFigure);
            app.SliderOF.ValueChangedFcn = createCallbackFcn(app, @SliderOFValueChanged, true);
            app.Slidernum_channels = uislider(app.UIFigure);
            app.Slidernum_channels.ValueChangedFcn = createCallbackFcn(app, @Slidernum_channelsValueChanged, true);
            app.expansionratioSliderLabel = uilabel(app.UIFigure);
            app.expansionratioSliderLabel.Text = 'expansion ratio';
            app.Sliderexpansion_ratio = uislider(app.UIFigure);
            app.Sliderexpansion_ratio.ValueChangedFcn = createCallbackFcn(app, @Sliderexpansion_ratioValueChanged, true);
            app.T_coolantdegCSlider_2Label = uilabel(app.UIFigure);
            app.T_coolantdegCSlider_2Label.Text = {'T_coolant'; '(deg C)'};
            app.SliderT_coolant = uislider(app.UIFigure);
            app.SliderT_coolant.ValueChangedFcn = createCallbackFcn(app, @SliderT_coolantValueChanged, true);
            app.dtmmLabel = uilabel(app.UIFigure);
            app.dtmmLabel.Text = {'mdot'; '(lbm/s)'};
            app.Slidermdot = uislider(app.UIFigure);
            app.Slidermdot.ValueChangedFcn = createCallbackFcn(app, @SlidermdotValueChanged, true);
            app.wallthicknessmmSliderLabel = uilabel(app.UIFigure);
            app.wallthicknessmmSliderLabel.Text = {'wall thickness'; '(mm)'};
            app.Sliderwallt = uislider(app.UIFigure);
            app.Sliderwallt.ValueChangedFcn = createCallbackFcn(app, @SliderwalltValueChanged, true);
            app.num_channelsSliderLabel = uilabel(app.UIFigure);
            app.num_channelsSliderLabel.Text = 'num_channels';
            app.k_wallWmKLabel = uilabel(app.UIFigure);
            app.k_wallWmKLabel.Text = 'k_wall (W/m-K)';
            app.Sliderk_wall = uislider(app.UIFigure);
            app.Sliderk_wall.ValueChangedFcn = createCallbackFcn(app, @Sliderk_wallValueChanged, true);
            app.ShowthermalstresslimitsButton = uibutton(app.UIFigure, 'state');
            app.ShowthermalstresslimitsButton.ValueChangedFcn = createCallbackFcn(app, @ShowthermalstresslimitsButtonValueChanged, true);
            app.ShowthermalstresslimitsButton.Text = 'Show thermal stress limits';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.viewofthroatTab = uitab(app.TabGroup);
            app.viewofthroatTab.Title = 'view of throat';
            app.UIAxesview_of_throat = uiaxes(app.viewofthroatTab);
            title(app.UIAxesview_of_throat, 'view of throat (units=mm)')
            xlabel(app.UIAxesview_of_throat, 'X')
            ylabel(app.UIAxesview_of_throat, 'Y')
            zlabel(app.UIAxesview_of_throat, 'Z')
            app.viewofwholeengineTab = uitab(app.TabGroup);
            app.viewofwholeengineTab.Title = 'view of whole engine';
            app.UIAxesview_of_whole_engine = uiaxes(app.viewofwholeengineTab);
            title(app.UIAxesview_of_whole_engine, 'view of whole engine (units=mm)')
            xlabel(app.UIAxesview_of_whole_engine, 'X')
            ylabel(app.UIAxesview_of_whole_engine, 'Y')
            zlabel(app.UIAxesview_of_whole_engine, 'Z')
            app.Volumemm3Label = uilabel(app.UIFigure);
            app.Volumemm3Label.Text = 'Volume (mm^3)';
            app.EditFieldvol_engine = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldvol_engine.Editable = 'off';
            app.Volumemm3Label_2 = uilabel(app.UIFigure);
            app.Volumemm3Label_2.Text = 'L_noz_parab (mm)';
            app.EditFieldL_nozzle_parabolic = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldL_nozzle_parabolic.Editable = 'off';
            app.Volumemm3Label_3 = uilabel(app.UIFigure);
            app.Volumemm3Label_3.Text = 'Re (mm)';
            app.EditFieldRe = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldRe.Editable = 'off';
            app.Volumemm3Label_4 = uilabel(app.UIFigure);
            app.Volumemm3Label_4.Text = 'thetaN (deg)';
            app.EditFieldthetaN = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldthetaN.Editable = 'off';
            app.Volumemm3Label_5 = uilabel(app.UIFigure);
            app.Volumemm3Label_5.Text = 'xN (mm)';
            app.EditFieldxN = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldxN.Editable = 'off';
            app.Volumemm3Label_6 = uilabel(app.UIFigure);
            app.Volumemm3Label_6.Text = 'R1 (mm)';
            app.EditFieldR1 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldR1.Editable = 'off';
            app.Volumemm3Label_7 = uilabel(app.UIFigure);
            app.Volumemm3Label_7.Text = 'R1p (mm)';
            app.EditFieldR1p = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldR1p.Editable = 'off';
            app.Volumemm3Label_8 = uilabel(app.UIFigure);
            app.Volumemm3Label_8.Text = 'alpha (deg)';
            app.EditFieldalpha = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldalpha.Editable = 'off';
            app.Volumemm3Label_9 = uilabel(app.UIFigure);
            app.Volumemm3Label_9.Text = 'L_chmb_crc_nrrw (mm)';
            app.EditFieldL_chamber_circular_narrow = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldL_chamber_circular_narrow.Editable = 'off';
            app.Volumemm3Label_10 = uilabel(app.UIFigure);
            app.Volumemm3Label_10.Text = 'Rc (mm)';
            app.EditFieldRc = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldRc.Editable = 'off';
            app.Volumemm3Label_11 = uilabel(app.UIFigure);
            app.Volumemm3Label_11.Text = 'L_chmb (mm)';
            app.EditFieldL_chamber_linear = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldL_chamber_linear.Editable = 'off';
            app.Volumemm3Label_12 = uilabel(app.UIFigure);
            app.Volumemm3Label_12.Text = 'dt (mm)';
            app.EditFielddt = uieditfield(app.UIFigure, 'numeric');
            app.EditFielddt.Editable = 'off';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = regenEngineTradesViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end